classdef BodePlotGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        AsymptoticBodePlotLabel       matlab.ui.control.Label
        TransferFunctionPanel         matlab.ui.container.Panel
        TransferFunctionField         matlab.ui.control.EditField
        TFSendButton                  matlab.ui.control.Button
        NumTFLabel                    matlab.ui.control.Label
        DivTFLabel                    matlab.ui.control.Label
        DenTFLabel                    matlab.ui.control.Label
        InfoTextPanel                 matlab.ui.container.Panel
        InfoTextArea                  matlab.ui.control.TextArea
        CenterPanel                   matlab.ui.container.Panel
        MagnitudePlotPanel            matlab.ui.container.Panel
        MagnitudePlotAxes             matlab.ui.control.UIAxes
        PhasePlotPanel                matlab.ui.container.Panel
        PhasePlotAxes                 matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        OptionsPanel                  matlab.ui.container.Panel
        AsymptoticCheckBox            matlab.ui.control.CheckBox
        GridCheckBox                  matlab.ui.control.CheckBox
        ElementsPanel                 matlab.ui.container.Panel
        ElementsListBox               matlab.ui.control.ListBox
        LegendPanel                   matlab.ui.container.Panel
        ConstantLabel                 matlab.ui.control.Label
        BodePlotLabel                 matlab.ui.control.Label
        AsymptoticLabel               matlab.ui.control.Label
        RealPoleLabel                 matlab.ui.control.Label
        RealZeroLabel                 matlab.ui.control.Label
        ComplexPoleLabel              matlab.ui.control.Label
        ComplexZeroLabel              matlab.ui.control.Label
        OriginPoleLabel               matlab.ui.control.Label
        OriginZeroLabel               matlab.ui.control.Label
        BodePlotGUIMenu               matlab.ui.container.Menu
        GithubMenu                    matlab.ui.container.Menu
        DocumentationitalianonlyMenu  matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

%-------------------------------------------------------------------------%    
    %Global Properties
    properties (Access = public)
        %Inizializing the global variables 
        transfer_function = {}; %tf evaluated from input
        zeros = {}; %z of zpkdata
        poles = {}; %p of zpkdata
        gain = 0; % k of zpkdata
        %values of everything, useful for creating max/min frequencies
        values = []; %array of all values
        types = [""]; %#ok<*NBRAK> %array of types of values
        multiplicity = []; %multiplicity of each value, used when plots are created
        w = 0;
        %As per BodePlotGUI, they are set as it is, they can change 
        minimumFrequency = 0;
        maximumFrequency = 0;
        %Used for calculing poles and zeros 
        Accuracy = 1E-3;
    end
%-------------------------------------------------------------------------%
    %Methods used for general purpose, mainly linked to transfer function
    methods (Access = private)
        %Resets mostly every property of the transfer function, in order to
        %avoid redudancy and double values in arrays
         function resetProperties(app)
            app.zeros = {}; %z of tfdata
            app.poles = {}; %p of tfdata
            app.gain = 0; % k of tfdata
            %values of everything, useful for creating max/min frequencies
            app.values = [];
            app.types = [""];
            app.w = 0;
            %As per BodePlotGUI, they are set as it is, they can change 
            app.minimumFrequency = 0.9;
            app.maximumFrequency = 1.1;
         end
         
        %Allows the user to get info directly on a specific textarea box
        function writeOnInfo(app,text)
             info_text = app.InfoTextArea.Value;
             info_text{end+1} = text;
             app.InfoTextArea.Value = string(info_text);
        end
        
        %Sets z,k,p,num and den  
        function setTransferFunctionValues(app)
            %Resetting everything in order to not have reduntant data
            resetProperties(app);
            %Getting the zeros, poles and gain data from tf
            [z,p,~] = zpkdata(app.transfer_function,'v');
            [num,den] = tfdata(app.transfer_function,'v');
            %Setting the constant, with "first" number found
            k=num(find(num~=0, 1, 'first' ))/den(find(den~=0, 1, 'first' ));
            app.zeros = z; app.poles = p; app.gain = k;     
        end
        
        %Sets the minimum and max frequencies 
        function setMinMaxFrequencies(app)
            %Setting values and types to check later
            app.values(1) = app.gain;
            app.multiplicity(1) = 1;
            %K = constant
            app.types(1) = "Constant";
            %Z = zero
            for i=1:length(app.zeros)
                app.values(end + 1) = app.zeros(i);
                app.types(end + 1) = "Zero";
                app.multiplicity(end + 1) = 1;
            end
            %P = pole
            for i=1:length(app.poles)
                app.values(end + 1) = app.poles(i);
                app.types(end + 1) = "Pole";
                app.multiplicity(end + 1) = 1;
            end               
            %Used for calculating the frequencies 
            MinF=realmax;
            MaxF=~realmax;
            for i=2:length(app.values)
                if (app.values(i)~=0)
                    MinF=min(MinF,abs(app.values(i)));
                    MaxF=max(MaxF,abs(app.values(i)));
                end
            end
            
            %If there is exclusively a pole or zero at origin, MinF and MaxF will
            % not have changed.  So set them arbitrarily to be near unity.
            if (MaxF==-realmax)
                MinF=0.9;
                MaxF=1.1;
            end
            
            %minimumFrequency is a bit more than an order of magnitude below lowest break.
            app.minimumFrequency=10^(floor(log10(MinF)-1.01));
            %maximumFrequency is a bit more than an order of magnitude above highest break.
            app.maximumFrequency=10^(ceil(log10(MaxF)+1.01));
            %Calculate 500 frequency points for plotting.
            app.w=logspace(log10(app.minimumFrequency),log10(app.maximumFrequency),1000); 
        end
        
        %Set the multiplicity based on type and values
        function setMultiplicity(app)
            %Check for multiplicity
            for i=2:length(app.values)
                for k=(i+1):length(app.values)
                    %Handle pole or zero at origin as special case.
                    if (app.values(i)==0)
                        %Multiple pole or zero at origin.
                        if (app.values(k)==0)
                            app.multiplicity(i)=app.multiplicity(i)+app.multiplicity(k);
                            %Set multiplicity of kth term to 0 to signify that it has been
                            % subsumed by term(i) (by increasing the ith term's multiplicity).
                            app.multiplicity(k)=0;
                        end
                        %We know term is not at origin, so check for (approximate) equality
                        %  Since we know this term is not at origin, we can divide by value.
                    elseif (abs((app.values(i)-app.values(k))/app.values(i)) < app.Accuracy)
                        app.multiplicity(i)=app.multiplicity(i)+app.multiplicity(k);
                        %Set multiplicity of kth term to 0 to signify that it has been
                        % subsumed by term(i) (by increasing the ith term's multiplicity).
                        app.multiplicity(k) = 0;
                    end
                end
            end
            %Sets the type of poles and zeros (real, origin, complex)
            i = 2;
            while (i<=length(app.values))
                %If root is at origin, handle it separately
                if (app.values(i)==0)
                   app.types(i) = "Origin " + app.types(i);
                    %If imaginary part is sufficiently small...
                elseif (abs(imag(app.values(i))/app.values(i))<app.Accuracy)
                    app.types(i)="Real " + app.types(i);  %...Add "Real" to type
                    app.values(i)= real(app.values(i));   %...And set imaginary part=0
                    %If imaginary part is *not* small...
                else
                    app.types(i)="Complex "+ app.types(i);  %...Add "Complex" to type
                    app.multiplicity(i + 1)=0;               %...Remove complex conjugate
                    i=i+1;                                  %...And skip conjugate.
                end
                i=i+1;  %Go to next root.
            end
            showElementsOnListBox(app);
            setMagnitudePlot(app);
        end
        
        function showElementsOnListBox(app)
            %This is only to show better the data on listbox 
                list_items = ["All","Bode Function"];
                list_itemsdata = ["All","Bode"];
                for i=1:length(app.types)
                    if (strcmp(app.types(i),"Pole") == 0 && strcmp(app.types(i),"Zero") == 0)
                        list_items(end + 1) = app.types(i) + " = " + string(app.values(i)); %#ok<*AGROW> 
                        list_itemsdata(end + 1) = app.types(i);
                    end
                end
                app.ElementsListBox.Items = list_items;   
                app.ElementsListBox.ItemsData = list_itemsdata;
        end
        
        function showTransferFunctionInLabel(app)
            %Shows the transfer function as H(s) = num/den in the label
            %below the text input 
            app.DivTFLabel.Visible = 'on';
            [num,den] = tfdata(app.transfer_function,'v');
            numString = poly2str(num,'s');
            denString = poly2str(den,'s');
            app.NumTFLabel.Text = numString;
            app.DenTFLabel.Text = denString;
        end
        
        function hideAllLegendLabels(app)
            %Hides all labels shown in Legend section. Later only the plots
            %shown will allow labels to be visible (magnitude plot)
             app.ConstantLabel.Visible = 'off';
             app.BodePlotLabel.Visible = 'off';
             app.AsymptoticLabel.Visible = 'off';
             app.RealPoleLabel.Visible = 'off';
             app.RealZeroLabel.Visible = 'off';
             app.ComplexPoleLabel.Visible = 'off';
             app.ComplexZeroLabel.Visible = 'off';
             app.OriginPoleLabel.Visible = 'off';
             app.OriginZeroLabel.Visible = 'off';
        end
    end
    

%-------------------------------------------------------------------------%
    %Methods used for creating PLOTS
    methods(Access=private)
        %Creates and sets the magnitude plot 
        function setMagnitudePlot(app) 
            plotSelected = app.ElementsListBox.Value;
            hideAllLegendLabels(app);
            if (isempty(plotSelected))
                plotSelected = "All";
            end
            %axes(app.MagnitudePlotAxes);
            %Plot line at 0dB for reference
            plot(app.MagnitudePlotAxes,[app.minimumFrequency app.maximumFrequency], [0 0],...
                'Color', '#000000', 'LineWidth', 2);
            %Creates magnitude approximation for later
            magnitude_approx = zeros(size(app.w));  
            %Peak indices for holding the indices at which peaks appear
            hold(app.MagnitudePlotAxes, "on");
            for i=1:length(app.values)
                switch app.types(i)
                    case 'Constant'
                        %A costant is unchanging from beginning to endù
                        f = [app.minimumFrequency, app.maximumFrequency];
                        m = 20*log10(abs([app.values(i) app.values(i)]));
                        if (strcmp(plotSelected,"All")==1 || strcmp(plotSelected,"Constant")==1)
                            plot(app.MagnitudePlotAxes,f,m,...
                            'Color', '#808080', 'LineWidth',1.5);
                            app.ConstantLabel.Visible = 'on';
                        end
                        magnitude_approx = magnitude_approx+...
                            interp1(log(f),m,log(app.w));
                    case 'Real Pole'
                        %A real pole has a single break frequence, and then
                        %decreases at 20dB per decade 
                        wo = -app.values(i);
                        f = [app.minimumFrequency wo app.maximumFrequency];
                        m = -20*log10([1 1 app.maximumFrequency/wo])*app.multiplicity(i);
                        if (strcmp(plotSelected,"All")==1  || strcmp(plotSelected,"Real Pole")==1 )
                            plot(app.MagnitudePlotAxes,f,m,...
                                'Color', '#99FF33', 'LineWidth',1.5);
                             app.RealPoleLabel.Visible = 'on';
                        end
                        magnitude_approx = magnitude_approx+...
                            interp1(log(f),m,log(app.w));
                    case 'Real Zero'
                        %similar to real pole, but increases instead of
                        %decreasing
                        wo = abs(app.values(i));
                        f = [app.minimumFrequency wo app.maximumFrequency];
                        m = 20*log10([1 1 app.maximumFrequency/wo])*app.multiplicity(i);
                        if (strcmp(plotSelected,"All")==1  || strcmp(plotSelected,"Real Zero")==1 )
                            plot(app.MagnitudePlotAxes,f,m,...
                                'Color', '#3399FF', 'LineWidth',1.5);
                             app.RealZeroLabel.Visible = 'on';
                        end
                        magnitude_approx = magnitude_approx+...
                            interp1(log(f),m,log(app.w));
                    case 'Complex Pole'
                        %A complex pole has a single break frequency and
                        %then decreases at 40dB per decade. There is also a
                        %peak value whose height and location are
                        %determined by the natural frequency and damping
                        %coefficient. A cicle will be plotted at the
                        %location of the peak
                        wn = abs(app.values(i));
                        theta = atan(abs(imag(app.values(i)/real(app.values(i)))));
                        zeta = cos(theta);
                        if (zeta < 0.5) %show the peak if zeta is less than 0.5
                            %First plots the peak, then the complex pole
                            peak = 2*zeta;
                            f = [app.minimumFrequency wn wn wn app.maximumFrequency];
                            m = -20*log10([1 1 peak 1 (app.maximumFrequency/wn)^2])*...
                                app.multiplicity(i);
                            if (strcmp(plotSelected,"All")==1  || strcmp(plotSelected,"Complex Pole")==1 )
                                plot(app.MagnitudePlotAxes,f,m,...
                                'Color', '#9933FF', 'LineWidth',1.5,'LineStyle',"--");
                                plot(app.MagnitudePlotAxes,wn,-20*log10(peak),'o','Color','#9933FF');
                                 app.ComplexPoleLabel.Visible = 'on';
                            end
                            %Doing again the calculations for the magnitude
                            %approximation 
                            f = [app.minimumFrequency wn app.maximumFrequency];
                            m = -20*log10([1 1 (app.maximumFrequency/wn)^2])*app.multiplicity(i);
                            %Now calculating the asymptomatic approximation
                            magnitude_approx = magnitude_approx+...
                            interp1(log(f),m,log(app.w));
                            index = find(app.w>=wn,1,'first');
                            magnitude_approx(index) = magnitude_approx(index) - ...
                                20*log10(peak);
                        else
                            %zeta is greater than or equal to 0.5
                            f = [app.minimumFrequency wn app.maximumFrequency];
                            m = -20*log10([1 1 (app.maximumFrequency/wn)^2])*...
                                app.multiplicity(i);
                            if (strcmp(plotSelected,"All")==1  || strcmp(plotSelected,"Complex Pole")==1 )
                                plot(app.MagnitudePlotAxes,f,m,...
                                'Color', '#9933FF' , 'LineWidth',1.5, "LineStyle","--");
                                app.ComplexPoleLabel.Visible = 'on';
                            end
                            magnitude_approx = magnitude_approx + ...
                                interp1(log(f),m,log(app.w)); %Builds up asymptotic approx
                        end
                    case 'Complex Zero'
                        %Its similar to complex pole, but increases instead
                        %of decreasing
                        wn = abs(app.values(i));
                        theta = atan(abs(imag(app.values(i)/real(app.values(i)))));
                        zeta = cos(theta);
                        if (zeta < 0.5) %show the peak if zeta is less than 0.5
                            %First plots the peak, then the complex pole
                            peak = 2*zeta;
                            f = [app.minimumFrequency wn wn wn app.maximumFrequency];
                            m = 20*log10([1 1 peak 1 (app.maximumFrequency/wn)^2])*...
                                app.multiplicity(i);
                            if (strcmp(plotSelected,"All")==1  || strcmp(plotSelected,"Complex Zero")==1 )
                                plot(app.MagnitudePlotAxes,f,m,...
                                'Color', '#FF33FF', 'LineWidth',1.5,"LineStyle","--");
                                plot(app.MagnitudePlotAxes,wn,20*log10(peak),'o','Color','#FF33FF');
                                 app.ComplexZeroLabel.Visible = 'on';
                            end
                            %Doing again the calculations for the magnitude
                            %approximation 
                            f = [app.minimumFrequency wn app.maximumFrequency];
                            m = 20*log10([1 1 (app.maximumFrequency/wn)^2])*app.multiplicity(i);
                            %Now calculating the asymptomatic approximation
                            magnitude_approx = magnitude_approx+...
                            interp1(log(f),m,log(app.w));
                            index = find(app.w>=wn,1,'first');
                            magnitude_approx(index) = magnitude_approx(index) - ...
                                20*log10(peak);
                        else
                            %zeta is greater than or equal to 0.5
                            f = [app.minimumFrequency wn app.maximumFrequency];
                            m = 20*log10([1 1 (app.maximumFrequency/wn)^2])*...
                                app.multiplicity(i);
                            if (strcmp(plotSelected,"All")==1  || strcmp(plotSelected,"Complex Zero")==1 )
                                plot(app.MagnitudePlotAxes,f,m,...
                                'Color', '#FF33FF', 'LineWidth',1.5,"LineStyle","--");
                                app.ComplexZeroLabel.Visible = 'on';
                            end
                            magnitude_approx = magnitude_approx + ...
                                interp1(log(f),m,log(app.w)); %Builds up asymptotic approx
                        end
                    case 'Origin Pole'
                        %A pole at teh origin is a monotonically decreasing
                        %straight line
                        f = [app.minimumFrequency app.maximumFrequency];
                        m = -20*log10([app.minimumFrequency app.maximumFrequency])*...
                            app.multiplicity(i);
                        if (strcmp(plotSelected,"All")==1  || strcmp(plotSelected,"Origin Pole")==1 )
                            plot(app.MagnitudePlotAxes,f,m,...
                                'Color', '#000FFF', 'LineWidth',1.5,'LineStyle',":");
                             app.OriginPoleLabel.Visible = 'on';
                        end
                        magnitude_approx = magnitude_approx + ...
                             interp1(log(f),m,log(app.w)); %Builds up asymptotic approx
                    case 'Origin Zero'
                        %A pole at teh origin is a monotonically decreasing
                        %straight line
                        f = [app.minimumFrequency app.maximumFrequency];
                        m = 20*log10([app.minimumFrequency app.maximumFrequency])*...
                            app.multiplicity(i);
                        if (strcmp(plotSelected,"All")==1  || strcmp(plotSelected,"Origin Zero")==1 )
                            plot(app.MagnitudePlotAxes,f,m,...
                                'Color', '#FF99FF', 'LineWidth',1.5,'LineStyle',":");
                             app.OriginZeroLabel.Visible = 'on';
                        end
                        magnitude_approx = magnitude_approx + ...
                             interp1(log(f),m,log(app.w)); %Builds up asymptotic approx
                end

            end
            [mg,~,w] = bode(app.transfer_function,app.w); %#ok<*ADPROP> 
            if (strcmp(plotSelected,"All")==1  || strcmp(plotSelected,"Bode")==1 )
                plot(app.MagnitudePlotAxes, w, 20*log10(mg(:)), ...
                    'Color', '#FF3333', 'LineWidth',2.0);  
                 app.BodePlotLabel.Visible = 'on';
            end
            if(app.AsymptoticCheckBox.Value == 1)
                 plot(app.MagnitudePlotAxes, w, magnitude_approx, ...
                'Color', '#FF9933', 'LineWidth',2.0,'LineStyle','--');  
                app.AsymptoticLabel.Visible = 'on';
            end
            
            hold(app.MagnitudePlotAxes,"off");
        end
        
        %Creates and sets the phase plot
        function setPhasePlot(app)
            plotSelected = app.ElementsListBox.Value;
            if (isempty(plotSelected))
                plotSelected = "All";
            end
            %axes(app.PhasePlotAxes);
            %Plot line at 0 for reference
             plot(app.PhasePlotAxes,[app.minimumFrequency app.maximumFrequency], [0 0],...
                'Color', 'black', 'LineWidth', 2);
            phase_approx = zeros(size(app.w));
            %For the phase plot is kinda the same procedure as the magnitude
            %plot, but in this case for SOME REASON, the phase_approx,
            %placed after the switch/case paragraph,does not give errors,
            %but magnitude_approx will give an error if placed like
            %phase_approx. Why?
            hold(app.PhasePlotAxes,'on');
            for i=1:length(app.values)
                switch app.types(i)
                    case 'Constant'
                        f = [app.minimumFrequency app.maximumFrequency];
                        if (app.values(i) > 0)
                            p=[0 0];
                        else
                            p =[180 180];
                        end
                       
                        if (strcmp(plotSelected,"All")==1 || strcmp(plotSelected,"Constant")==1)
                            plot(app.PhasePlotAxes,f,p,...
                            'Color', '#808080', 'LineWidth',1.5);
                        end
                    case 'Real Pole'
                        wo = -app.values(i);
                        f = [app.minimumFrequency wo/10 wo*10 app.maximumFrequency];
                        p=[0 0 -90 -90]*app.multiplicity(i);
                       
                        if (strcmp(plotSelected,"All")==1  || strcmp(plotSelected,"Real Pole")==1 )
                            plot(app.PhasePlotAxes,f,p,...
                                'Color', '#99FF33', 'LineWidth',1.5);
                        end
                    case 'Real Zero'
                        if (app.values(i) > 0)
                            wo = app.values(i);
                            p = [0 0 -90 -90]*app.multiplicity(i);
                        else
                            wo = -app.values(i);
                            p = [0 0 90 90]*app.multiplicity(i);
                        end
                        f=[app.minimumFrequency wo/10 wo*10 app.maximumFrequency];
                      
                        if (strcmp(plotSelected,"All")==1  || strcmp(plotSelected,"Real Zero")==1 )
                            plot(app.PhasePlotAxes,f,p,...
                                'Color', '#3399FF', 'LineWidth',1.5);
                        end
                    case 'Complex Pole'
                        wo = abs(app.values(i));
                        theta = atan(abs(imag(app.values(i)/real(app.values(i)))));
                        zeta = cos(theta);
                        bf = 0.1^zeta;
                        f = [app.minimumFrequency wo*bf wo/bf app.maximumFrequency];

                         if (strcmp(plotSelected,"All")==1  || strcmp(plotSelected,"Complex Pole")==1 )
                                plot(app.PhasePlotAxes,f,p,...
                                'Color', '#9933FF', 'LineWidth',1.5, "LineStyle","--");
                         end
                    case 'Complex Zero'
                        wo = abs(app.values(i));
                        theta = atan(abs(imag(app.values(i)/real(app.values(i)))));
                        zeta = cos(theta);
                        bf = 0.1^zeta;
                        f = [app.minimumFrequency wo*bf wo/bf app.maximumFrequency];
                        if (real(app.values(i)) > 0)
                            p = [0 0 -180 -180]*app.multiplicity(i);
                        else
                            p = [0 0 180 180]*app.multiplicity(i);
                        end
                       
                        if (strcmp(plotSelected,"All")==1  || strcmp(plotSelected,"Complex Zero")==1 )
                                plot(app.PhasePlotAxes,f,p,...
                                'Color', '#FF33FF', 'LineWidth',1.5,"LineStyle","--");
                        end
                    case 'Origin Pole'
                        f = [app.minimumFrequency app.maximumFrequency];
                        p = [-90 -90]*app.multiplicity(i);
                        if (app.RadiantsCheckBox.Value == 1)
                            p=p/180;
                        end
                        if (strcmp(plotSelected,"All")==1  || strcmp(plotSelected,"Origin Pole")==1 )
                            plot(app.PhasePlotAxes,f,p,...
                                'Color', '#000FFF', 'LineWidth',1.5,'LineStyle',":");
                        end
                    case 'Origin Zero'
                        f = [app.minimumFrequency app.maximumFrequency];
                        p = [90 90]*app.multiplicity(i);
                       
                        if (strcmp(plotSelected,"All")==1  || strcmp(plotSelected,"Origin Zero")==1 )
                            plot(app.PhasePlotAxes,f,p,...
                                'Color', '#FF99FF', 'LineWidth',1.5,'LineStyle',":");
                        end 
                phase_approx = phase_approx + interp1(log(f),p,log(app.w));
                end
                
            end
            [~,ph,w] = bode(app.transfer_function, app.w);
            if (strcmp(plotSelected,"All")==1  || strcmp(plotSelected,"Bode")==1 )
                plot(app.PhasePlotAxes, w,ph(:), ...
                    'Color', '#FF3333', 'LineWidth',2.0);  
            end
            if(app.AsymptoticCheckBox.Value == 1)
                phase_approx = phase_approx+(ph(1) - phase_approx(1));
                 plot(app.PhasePlotAxes, w, phase_approx, ...
                'Color', '#FF9933', 'LineWidth',2.0,'LineStyle','--');  
            end
            hold(app.PhasePlotAxes,'off');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TFSendButton
        function TransferFunctionCheck(app, event)
            %Checks if there is a function inserted in the text box
            app.transfer_function = app.TransferFunctionField.Value;
            
            if (isempty(app.transfer_function))
                %The field is empty
                writeOnInfo(app,"Please insert a function!");
            else
                %The field is not empty
                writeOnInfo(app,"Function inserted. Checking it...");
                %Checks if eval(tf) is ok. Try/catch because it gives an
                %error that blocks the programs
                try
                    app.transfer_function = eval("tf("+...
                        string(app.transfer_function)+")");
                    
                    
                catch 
                    writeOnInfo(app,"Error! Function is not recognizable!");   
                end
                showTransferFunctionInLabel(app);
                if (isproper(app.transfer_function))
                    try
                        %Everything is now okay! Now some functions will be
                        %launched to set the elements like zeros, poles, gain and
                        %other things!
                        writeOnInfo(app,"Setting values!");
                        setTransferFunctionValues(app);
                        writeOnInfo(app,"Getting maximum and minimum frequencies...");
                        setMinMaxFrequencies(app);
                        writeOnInfo(app,"Maximum Frequency: " + string(app.maximumFrequency));
                        writeOnInfo(app,"Minimum Frequency: " + string(app.minimumFrequency));
                        setMultiplicity(app);
                        writeOnInfo(app,"Showing data!");
                        for i=1:length(app.values)
                            if (strcmp(app.types(i),"Pole") == 0 && strcmp(app.types(i),"Zero") == 0)
                                writeOnInfo(app, app.types(i) + " with value " +...
                                    string(app.values(i)));
                            end
                        end
                        setMagnitudePlot(app);
                        setPhasePlot(app);
                        
                    catch ME
                        writeOnInfo(app,"Error during plotting!");
                        writeOnInfo(app, ME.message);
                    end
                else
                    writeOnInfo(app,"The function is not proper!");
                end
                
            end
            
        end

        % Value changed function: ElementsListBox
        function ElementsListBoxValueChanged(app, event)
            value = app.ElementsListBox.Value;
            writeOnInfo(app,"Showing " + string(value));
            setMagnitudePlot(app);
            setPhasePlot(app);
        end

        % Value changed function: GridCheckBox
        function GridCheckBoxValueChanged(app, event)
            value = app.GridCheckBox.Value;
            if (value == 1)
                grid(app.MagnitudePlotAxes,'on');
                grid(app.PhasePlotAxes,'on');
            else
                grid(app.MagnitudePlotAxes,'off');
                grid(app.PhasePlotAxes,'off');
            end
        end

        % Value changed function: AsymptoticCheckBox
        function AsymptoticCheckBoxValueChanged(app, event)
            if (~isempty(app.transfer_function))
                setMagnitudePlot(app);
                setPhasePlot(app);
            end
            
        end

        % Menu selected function: GithubMenu
        function GithubMenuSelected(app, event)
            url = 'https://github.com/matteomarzio?tab=repositories';
            web(url);
        end

        % Menu selected function: DocumentationitalianonlyMenu
        function DocumentationitalianonlyMenuSelected(app, event)
            try
                winopen("BodePlotGUI_GUIDE.pdf");
            catch 
                writeOnInfo(app,"Errore! La documentazione non è presente nella directory! Ottengo la versione online...");
            end 
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {256, '1x', 272};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AsymptoticBodePlotLabel = uilabel(app.LeftPanel);
            app.AsymptoticBodePlotLabel.Text = 'Asymptotic Bode Plot';
            app.TransferFunctionPanel = uipanel(app.LeftPanel);
            app.TransferFunctionPanel.Title = 'Transfer Function';
            app.TransferFunctionField = uieditfield(app.TransferFunctionPanel, 'text');
            app.TFSendButton = uibutton(app.TransferFunctionPanel, 'push');
            app.TFSendButton.ButtonPushedFcn = createCallbackFcn(app, @TransferFunctionCheck, true);
            app.TFSendButton.Text = 'Check';
            app.NumTFLabel = uilabel(app.TransferFunctionPanel);
            app.NumTFLabel.Text = '';
            app.DivTFLabel = uilabel(app.TransferFunctionPanel);
            app.DivTFLabel.Visible = 'off';
            app.DivTFLabel.Text = 'H(s) = -----------------------------';
            app.DenTFLabel = uilabel(app.TransferFunctionPanel);
            app.DenTFLabel.Text = '';
            app.InfoTextPanel = uipanel(app.LeftPanel);
            app.InfoTextPanel.Title = 'Info & Text';
            app.InfoTextArea = uitextarea(app.InfoTextPanel);
            app.InfoTextArea.Editable = 'off';
            app.CenterPanel = uipanel(app.GridLayout);
            app.MagnitudePlotPanel = uipanel(app.CenterPanel);
            app.MagnitudePlotPanel.Title = 'Magnitude Plot';
            app.MagnitudePlotAxes = uiaxes(app.MagnitudePlotPanel);
            xlabel(app.MagnitudePlotAxes, 'Frequency - \omega, rad-sec^-1')
            ylabel(app.MagnitudePlotAxes, 'Magnitude - dB')
            app.MagnitudePlotAxes.XTick = [0.1 1 10 100 1000 10000];
            app.MagnitudePlotAxes.XScale = 'log';
            app.MagnitudePlotAxes.XTickLabel = {'10^{-1}'; '10^0'; '10^1'; '10^2'; '10^3'; '10^4'};
            app.MagnitudePlotAxes.YTick = [-40 -20 0 20 40 60];
            app.MagnitudePlotAxes.YTickLabel = {'-40'; '-20'; '0'; '20'; '40'; '60'};
            app.PhasePlotPanel = uipanel(app.CenterPanel);
            app.PhasePlotPanel.Title = 'Phase Plot';
            app.PhasePlotAxes = uiaxes(app.PhasePlotPanel);
            xlabel(app.PhasePlotAxes, 'Frequency - \omega, rad-sec^-1')
            ylabel(app.PhasePlotAxes, 'Phase - degreees')
            app.PhasePlotAxes.XTick = [0.1 1 10 100 1000 10000];
            app.PhasePlotAxes.XScale = 'log';
            app.PhasePlotAxes.XTickLabel = {'10^{-1}'; '10^0'; '10^1'; '10^2'; '10^3'; '10^4'};
            app.PhasePlotAxes.YTick = [-90 -45 0 45 90];
            app.PhasePlotAxes.YTickLabel = {'-90'; '-45'; '0'; '45'; '90'};
            app.RightPanel = uipanel(app.GridLayout);
            app.OptionsPanel = uipanel(app.RightPanel);
            app.OptionsPanel.Title = 'Options';
            app.AsymptoticCheckBox = uicheckbox(app.OptionsPanel);
            app.AsymptoticCheckBox.ValueChangedFcn = createCallbackFcn(app, @AsymptoticCheckBoxValueChanged, true);
            app.AsymptoticCheckBox.Text = 'Asymptotic';
            app.GridCheckBox = uicheckbox(app.OptionsPanel);
            app.GridCheckBox.ValueChangedFcn = createCallbackFcn(app, @GridCheckBoxValueChanged, true);
            app.GridCheckBox.Text = 'Grid';
            app.ElementsPanel = uipanel(app.RightPanel);
            app.ElementsPanel.Title = 'Elements shown in the Plot';
            app.ElementsListBox = uilistbox(app.ElementsPanel);
            app.ElementsListBox.Items = {};
            app.ElementsListBox.ValueChangedFcn = createCallbackFcn(app, @ElementsListBoxValueChanged, true);
            app.ElementsListBox.Value = {};
            app.LegendPanel = uipanel(app.RightPanel);
            app.LegendPanel.Title = 'Legend';
            app.ConstantLabel = uilabel(app.LegendPanel);
            app.ConstantLabel.Text = 'Constant';
            app.BodePlotLabel = uilabel(app.LegendPanel);
            app.BodePlotLabel.Text = 'Bode Plot';
            app.AsymptoticLabel = uilabel(app.LegendPanel);
            app.AsymptoticLabel.Text = 'Asymptotic';
            app.RealPoleLabel = uilabel(app.LegendPanel);
            app.RealPoleLabel.Text = 'Real Pole';
            app.RealZeroLabel = uilabel(app.LegendPanel);
            app.RealZeroLabel.Text = 'Real Zero';
            app.ComplexPoleLabel = uilabel(app.LegendPanel);
            app.ComplexPoleLabel.Text = 'Complex Pole';
            app.ComplexZeroLabel = uilabel(app.LegendPanel);
            app.ComplexZeroLabel.Text = 'Complex Zero';
            app.OriginPoleLabel = uilabel(app.LegendPanel);
            app.OriginPoleLabel.Text = 'Origin Pole';
            app.OriginZeroLabel = uilabel(app.LegendPanel);
            app.OriginZeroLabel.Text = 'Origin Zero';
            app.BodePlotGUIMenu = uimenu(app.UIFigure);
            app.BodePlotGUIMenu.Text = 'Bode Plot GUI  ';
            app.GithubMenu = uimenu(app.BodePlotGUIMenu);
            app.GithubMenu.MenuSelectedFcn = createCallbackFcn(app, @GithubMenuSelected, true);
            app.GithubMenu.Text = 'Github';
            app.DocumentationitalianonlyMenu = uimenu(app.BodePlotGUIMenu);
            app.DocumentationitalianonlyMenu.MenuSelectedFcn = createCallbackFcn(app, @DocumentationitalianonlyMenuSelected, true);
            app.DocumentationitalianonlyMenu.Text = 'Documentation (italian only)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BodePlotGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end